classdef FCC_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout4           matlab.ui.container.GridLayout
        Label                 matlab.ui.control.Label
        MethodsPanel          matlab.ui.container.Panel
        RK4CheckBox           matlab.ui.control.CheckBox
        RK3CheckBox           matlab.ui.control.CheckBox
        RK2CheckBox           matlab.ui.control.CheckBox
        RK1CheckBox           matlab.ui.control.CheckBox
        PLOTButton            matlab.ui.control.Button
        ReactorSettingsPanel  matlab.ui.container.Panel
        GridLayout3           matlab.ui.container.GridLayout
        ToKnob                matlab.ui.control.Knob
        ToKnobLabel           matlab.ui.control.Label
        XoSlider              matlab.ui.control.Slider
        XoSliderLabel         matlab.ui.control.Label
        delHSlider            matlab.ui.control.Slider
        delHSliderLabel       matlab.ui.control.Label
        KineticsPanel         matlab.ui.container.Panel
        GridLayout            matlab.ui.container.GridLayout
        KdoSlider             matlab.ui.control.Slider
        KdoSliderLabel        matlab.ui.control.Label
        EaSlider              matlab.ui.control.Slider
        EaSliderLabel         matlab.ui.control.Label
        EadSlider             matlab.ui.control.Slider
        EadSliderLabel        matlab.ui.control.Label
        KoSlider              matlab.ui.control.Slider
        KoSliderLabel         matlab.ui.control.Label
        PropertiesPanel       matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        hSpinner              matlab.ui.control.Spinner
        hSpinnerLabel         matlab.ui.control.Label
        rhoSlider             matlab.ui.control.Slider
        rhoSliderLabel        matlab.ui.control.Label
        CpSlider              matlab.ui.control.Slider
        CpSliderLabel         matlab.ui.control.Label
        ZfSlider              matlab.ui.control.Slider
        ZfSliderLabel         matlab.ui.control.Label
        TabGroup              matlab.ui.container.TabGroup
        ConversionTab         matlab.ui.container.Tab
        GridLayout5           matlab.ui.container.GridLayout
        UIAxes                matlab.ui.control.UIAxes
        TemperatureTab        matlab.ui.container.Tab
        GridLayout6           matlab.ui.container.GridLayout
        UIAxes_2              matlab.ui.control.UIAxes
        ActivityTab           matlab.ui.container.Tab
        GridLayout7           matlab.ui.container.GridLayout
        UIAxes_3              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
    % Set default values for all sliders
    app.KoSlider.Value = 1.2;
    app.EaSlider.Value = 8000;
    app.EadSlider.Value=6000;
    app.KdoSlider.Value = 0.5;
    app.delHSlider.Value = -30000;
    app.CpSlider.Value = 100;
    app.rhoSlider.Value = 800;
    app.ToKnob.Value = 750;
    app.ZfSlider.Value=100;
    app.hSpinner.Value=0.1;

    % set default properties for plots
    grid(app.UIAxes, 'on');
    app.UIAxes.XMinorGrid = 'on';
    app.UIAxes.YMinorGrid = 'on';

    grid(app.UIAxes_2, 'on');
    app.UIAxes_2.XMinorGrid = 'on';
    app.UIAxes_2.YMinorGrid = 'on';

    grid(app.UIAxes_3, 'on');
    app.UIAxes_3.XMinorGrid = 'on';
    app.UIAxes_3.YMinorGrid = 'on';


    %set default properties for knob
    % Set major ticks every 200 units (labeled)
app.ToKnob.MajorTicks = 0:200:1000;

% Set minor ticks every 20 units (unlabeled fine grid lines)
app.ToKnob.MinorTicks = 0:20:1000;

%Latex formatted labels
% Ko
app.KoSliderLabel.Text = '$\mathit{K_o}$';
app.KoSliderLabel.Interpreter = 'latex';

% Ea
app.EaSliderLabel.Text = '$\mathit{E_a}$';
app.EaSliderLabel.Interpreter = 'latex';

% Kdo
app.KdoSliderLabel.Text = '$\mathit{K_{do}}$';
app.KdoSliderLabel.Interpreter = 'latex';

% Ead
app.EadSliderLabel.Text = '$\mathit{E_{ad}}$';
app.EadSliderLabel.Interpreter = 'latex';

% Zf
app.ZfSliderLabel.Text = '$\mathit{Z_f}$';
app.ZfSliderLabel.Interpreter = 'latex';

% rho
app.rhoSliderLabel.Text = '$\mathit{\rho}$';
app.rhoSliderLabel.Interpreter = 'latex';

% Cp
app.CpSliderLabel.Text = '$\mathit{C_p}$';
app.CpSliderLabel.Interpreter = 'latex';

% delH
app.delHSliderLabel.Text = '$\mathit{\Delta H}$';
app.delHSliderLabel.Interpreter = 'latex';

% Xo
app.XoSliderLabel.Text = '$\mathit{X_o}$';
app.XoSliderLabel.Interpreter = 'latex';

% To (dial)
app.ToKnobLabel.Text = '$\mathit{T_o}$';
app.ToKnobLabel.Interpreter = 'latex';

% Step size
app.hSpinnerLabel.Text = '$\mathit{h}$';
app.hSpinnerLabel.Interpreter = 'latex';
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
           % Show "Solving..." status
app.Label.Text = 'Solving...';
drawnow;  % Forces UI update
% Check if no RK method is selected
if ~any([app.RK1CheckBox.Value, app.RK2CheckBox.Value, app.RK3CheckBox.Value, app.RK4CheckBox.Value])
    app.Label.Text = '⚠️ Please select at least one method to plot.';
    return;
end

% Extract parameter values from sliders
params.k0  = app.KoSlider.Value;
params.Ea  = app.EaSlider.Value;
params.kd0 = app.KdoSlider.Value;
params.Ead = app.EadSlider.Value;
params.R   = 8.314;  % constant
params.dH  = -app.delHSlider.Value;
params.Cp  = app.CpSlider.Value;
params.rho = app.rhoSlider.Value;

% Initial conditions
X0 = app.XoSlider.Value;
T0 = app.ToKnob.Value;
A0 = 1;  % assuming catalyst activity starts at 1
Y0 = [X0; T0; A0];

% Integration settings
Zf = app.ZfSlider.Value;
Zspan = [0, Zf];
h = app.hSpinner.Value;

% Clear all axes before plotting
cla(app.UIAxes); cla(app.UIAxes_2); cla(app.UIAxes_3);
hold(app.UIAxes, 'on');
hold(app.UIAxes_2, 'on');
hold(app.UIAxes_3, 'on');

if app.RK1CheckBox.Value
    [Z1, Y1] = RK1(Zspan, Y0, h, params);
    plot(app.UIAxes, Z1, Y1(1,:), '-', 'Color', [0.85 0.1 0.1], 'LineWidth', 1.5, 'Marker', 'o', 'MarkerIndices', 1:20:length(Z1), 'DisplayName', 'RK1');
    plot(app.UIAxes_2, Z1, Y1(2,:), '-', 'Color', [0.85 0.1 0.1], 'LineWidth', 1.5, 'Marker', 'o', 'MarkerIndices', 1:20:length(Z1), 'DisplayName', 'RK1');
    plot(app.UIAxes_3, Z1, Y1(3,:), '-', 'Color', [0.85 0.1 0.1], 'LineWidth', 1.5, 'Marker', 'o', 'MarkerIndices', 1:20:length(Z1), 'DisplayName', 'RK1');
end

if app.RK2CheckBox.Value
    [Z2, Y2] = RK2(Zspan, Y0, h, params);
    plot(app.UIAxes, Z2, Y2(1,:), '--', 'Color', [0.1 0.6 0.1], 'LineWidth', 1.5, 'Marker', 's', 'MarkerIndices', 1:20:length(Z2), 'DisplayName', 'RK2');
    plot(app.UIAxes_2, Z2, Y2(2,:), '--', 'Color', [0.1 0.6 0.1], 'LineWidth', 1.5, 'Marker', 's', 'MarkerIndices', 1:20:length(Z2), 'DisplayName', 'RK2');
    plot(app.UIAxes_3, Z2, Y2(3,:), '--', 'Color', [0.1 0.6 0.1], 'LineWidth', 1.5, 'Marker', 's', 'MarkerIndices', 1:20:length(Z2), 'DisplayName', 'RK2');
end

if app.RK3CheckBox.Value
    [Z3, Y3] = RK3(Zspan, Y0, h, params);
    plot(app.UIAxes, Z3, Y3(1,:), '-.', 'Color', [0.1 0.3 0.9], 'LineWidth', 1.5, 'Marker', '^', 'MarkerIndices', 1:20:length(Z3), 'DisplayName', 'RK3');
    plot(app.UIAxes_2, Z3, Y3(2,:), '-.', 'Color', [0.1 0.3 0.9], 'LineWidth', 1.5, 'Marker', '^', 'MarkerIndices', 1:20:length(Z3), 'DisplayName', 'RK3');
    plot(app.UIAxes_3, Z3, Y3(3,:), '-.', 'Color', [0.1 0.3 0.9], 'LineWidth', 1.5, 'Marker', '^', 'MarkerIndices', 1:20:length(Z3), 'DisplayName', 'RK3');
end

if app.RK4CheckBox.Value
    [Z4, Y4] = RK4(Zspan, Y0, h, params);
    plot(app.UIAxes, Z4, Y4(1,:), ':', 'Color', [0.2 0.2 0.2], 'LineWidth', 1.5, 'Marker', 'd', 'MarkerIndices', 1:20:length(Z4), 'DisplayName', 'RK4');
    plot(app.UIAxes_2, Z4, Y4(2,:), ':', 'Color', [0.2 0.2 0.2], 'LineWidth', 1.5, 'Marker', 'd', 'MarkerIndices', 1:20:length(Z4), 'DisplayName', 'RK4');
    plot(app.UIAxes_3, Z4, Y4(3,:), ':', 'Color', [0.2 0.2 0.2], 'LineWidth', 1.5, 'Marker', 'd', 'MarkerIndices', 1:20:length(Z4), 'DisplayName', 'RK4');
end

% Label the Conversion plot
title(app.UIAxes, 'Fractional Conversion of Hydrocarbon (X) vs Reactor Length (Z)');
xlabel(app.UIAxes, 'Reactor Length (Z)');
ylabel(app.UIAxes, 'X');
legend(app.UIAxes, 'show');
grid(app.UIAxes, 'on');

% Label the Temperature plot
title(app.UIAxes_2, 'Temperature Profile (T) vs Reactor Length (Z)');
xlabel(app.UIAxes_2, 'Reactor Length (Z)');
ylabel(app.UIAxes_2, 'T (K)');
legend(app.UIAxes_2, 'show');
grid(app.UIAxes_2, 'on');

% Label the Catalyst Activity plot
title(app.UIAxes_3, 'Catalyst Activity (A) vs Reactor Length (Z)');
xlabel(app.UIAxes_3, 'Reactor Length (Z)');
ylabel(app.UIAxes_3, 'A');
legend(app.UIAxes_3, 'show');
grid(app.UIAxes_3, 'on');

% Show completion message
app.Label.Text = 'Plot updated successfully ✅';
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
    if strcmp(key, 'return') || strcmp(key, 'enter')

        PLOTButtonPushed(app, event);  % trigger the Plot button's code
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout4 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout4);
            app.ConversionTab = uitab(app.TabGroup);
            app.ConversionTab.Title = 'Conversion';
            app.GridLayout5 = uigridlayout(app.ConversionTab);
            app.UIAxes = uiaxes(app.GridLayout5);
            title(app.UIAxes, 'Fractional Conversion')
            xlabel(app.UIAxes, 'Reactor Length(Z)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TemperatureTab = uitab(app.TabGroup);
            app.TemperatureTab.Title = 'Temperature';
            app.GridLayout6 = uigridlayout(app.TemperatureTab);
            app.UIAxes_2 = uiaxes(app.GridLayout6);
            title(app.UIAxes_2, 'Temperature')
            xlabel(app.UIAxes_2, 'Reactor Length(Z)')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ActivityTab = uitab(app.TabGroup);
            app.ActivityTab.Title = 'Activity';
            app.GridLayout7 = uigridlayout(app.ActivityTab);
            app.UIAxes_3 = uiaxes(app.GridLayout7);
            title(app.UIAxes_3, 'Catalyst Activity')
            xlabel(app.UIAxes_3, 'Reactor Length(Z)')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.PropertiesPanel = uipanel(app.GridLayout4);
            app.PropertiesPanel.Title = 'Properties';
            app.GridLayout2 = uigridlayout(app.PropertiesPanel);
            app.ZfSliderLabel = uilabel(app.GridLayout2);
            app.ZfSliderLabel.Text = 'Zf';
            app.ZfSlider = uislider(app.GridLayout2);
            app.ZfSlider.Limits = [0 150];
            app.ZfSlider.Tag = 'SliderZspan';
            app.CpSliderLabel = uilabel(app.GridLayout2);
            app.CpSliderLabel.Text = 'Cp';
            app.CpSlider = uislider(app.GridLayout2);
            app.CpSlider.Limits = [0 200];
            app.CpSlider.Tag = 'SliderCp';
            app.rhoSliderLabel = uilabel(app.GridLayout2);
            app.rhoSliderLabel.Text = 'rho';
            app.rhoSlider = uislider(app.GridLayout2);
            app.rhoSlider.Limits = [0 1500];
            app.rhoSlider.Tag = 'Sliderrho';
            app.hSpinnerLabel = uilabel(app.GridLayout2);
            app.hSpinnerLabel.Text = 'h';
            app.hSpinner = uispinner(app.GridLayout2);
            app.hSpinner.Step = 0.1;
            app.hSpinner.Limits = [0 10];
            app.KineticsPanel = uipanel(app.GridLayout4);
            app.KineticsPanel.Title = 'Kinetics';
            app.GridLayout = uigridlayout(app.KineticsPanel);
            app.KoSliderLabel = uilabel(app.GridLayout);
            app.KoSliderLabel.Text = 'Ko';
            app.KoSlider = uislider(app.GridLayout);
            app.KoSlider.Limits = [0 10];
            app.KoSlider.Tag = 'SliderKo';
            app.EadSliderLabel = uilabel(app.GridLayout);
            app.EadSliderLabel.Text = 'Ead';
            app.EadSlider = uislider(app.GridLayout);
            app.EadSlider.Limits = [0 10000];
            app.EadSlider.Tag = 'SliderEad';
            app.EaSliderLabel = uilabel(app.GridLayout);
            app.EaSliderLabel.Text = 'Ea';
            app.EaSlider = uislider(app.GridLayout);
            app.EaSlider.Limits = [0 10000];
            app.EaSlider.Tag = 'SliderEa';
            app.KdoSliderLabel = uilabel(app.GridLayout);
            app.KdoSliderLabel.Text = 'Kdo';
            app.KdoSlider = uislider(app.GridLayout);
            app.KdoSlider.Limits = [0 10];
            app.KdoSlider.Tag = 'SliderKdo';
            app.ReactorSettingsPanel = uipanel(app.GridLayout4);
            app.ReactorSettingsPanel.Title = 'Reactor Settings';
            app.GridLayout3 = uigridlayout(app.ReactorSettingsPanel);
            app.delHSliderLabel = uilabel(app.GridLayout3);
            app.delHSliderLabel.Text = 'delH';
            app.delHSlider = uislider(app.GridLayout3);
            app.delHSlider.Limits = [-40000 40000];
            app.XoSliderLabel = uilabel(app.GridLayout3);
            app.XoSliderLabel.Text = 'Xo';
            app.XoSlider = uislider(app.GridLayout3);
            app.XoSlider.Limits = [0 1];
            app.ToKnobLabel = uilabel(app.GridLayout3);
            app.ToKnobLabel.Text = 'To';
            app.ToKnob = uiknob(app.GridLayout3, 'continuous');
            app.ToKnob.Limits = [0 1000];
            app.PLOTButton = uibutton(app.GridLayout4, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.MethodsPanel = uipanel(app.GridLayout4);
            app.MethodsPanel.Title = 'Methods';
            app.RK1CheckBox = uicheckbox(app.MethodsPanel);
            app.RK1CheckBox.Text = 'RK1';
            app.RK2CheckBox = uicheckbox(app.MethodsPanel);
            app.RK2CheckBox.Text = 'RK2';
            app.RK3CheckBox = uicheckbox(app.MethodsPanel);
            app.RK3CheckBox.Text = 'RK3';
            app.RK4CheckBox = uicheckbox(app.MethodsPanel);
            app.RK4CheckBox.Text = 'RK4';
            app.Label = uilabel(app.GridLayout4);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FCC_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end